classdef DimerOrganizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ImportrawdataButton           matlab.ui.control.Button
        displaybox                    matlab.ui.control.ListBox
        EditButton                    matlab.ui.control.Button
        SavedataButton                matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        LoadplatedataButton           matlab.ui.control.Button
        AddSelectedButton             matlab.ui.control.Button
        AddAllButton                  matlab.ui.control.Button
        LoadAnalysisButton            matlab.ui.control.Button
        SaveAnalysisButton            matlab.ui.control.Button
        AnalyzeLabel                  matlab.ui.control.Label
        AnnotateLabel                 matlab.ui.control.Label
        ViewAnalysisButton            matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        BackgroundsubtractedCheckBox  matlab.ui.control.CheckBox
        ODnormalizedCheckBox          matlab.ui.control.CheckBox
        SetblankButton                matlab.ui.control.Button
        FileLabel                     matlab.ui.control.Label
        FilenameLabel                 matlab.ui.control.Label
        AnalysisLabel                 matlab.ui.control.Label
        AnalysisFilenameLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data_struct = struct; % Description
        sample_info = struct;
        plot_data = struct;
        analysis = struct;
    end
    
    methods (Access = private)
        
        function parse_data(app, full_path)
            inputfile_id = fopen(full_path);
            input_text = textscan(inputfile_id, '%s', 'Delimiter', '\n');
            
            data_struct = struct;
            ln = 1;
            while ln < length(input_text{1})
                line = input_text{1}{ln};
                if ~isempty(line) && strcmp(line(1), '1')
                    parsed_line = textscan(line, '%s');
                    if strcmp(parsed_line{1}{2}, '2')
                        
                        new_matrix = zeros(8, 12);
                        for bid = 1:8
                            next_row = textscan(input_text{1}{ln + bid}, '%s', 'Delimiter', '\t');
                            for col_idx = 1:12
                                col_item = next_row{1}{col_idx + 1};
                                if ~isempty(str2num(col_item)) && length(str2num(col_item)) == 1
                                    new_matrix(bid, col_idx) = str2num(col_item);
                                end
                            end
                        end
                        prev_line = input_text{1}{ln - 1};
                        if strcmp(prev_line, 'FPread:500,541')
                            data_struct.Venus = new_matrix;
                        elseif strcmp(prev_line, 'FPread:570,616')
                            data_struct.Scarlet = new_matrix;
                        elseif strfind(prev_line, 'OD')
                            data_struct.OD = new_matrix;
                        elseif strfind(prev_line, 'Time')
                            if ~isfield(data_struct, 'Luc')
                                data_struct.Luc = new_matrix;
                            else
                                data_struct.Luc = cat(3, data_struct.Luc, new_matrix);
                            end
                        end
                        ln = ln + 8;
                        
                    end
                end
                ln = ln + 1;
            end
            app.data_struct = data_struct;
            %id_table.Data = ;
            app.UITable.Data = cell(8, 12);
            app.UITable.RowName = {'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'};
            app.UITable.ColumnName = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            
            app.displaybox.Enable = 'on';
            
        end
        
        function initialize_sample_info(app)
            % initialize sample info
            app.sample_info.fy4 = cell(8, 12);
            app.sample_info.fy5 = cell(8, 12);
            app.sample_info.dose = cell(8, 12);
            app.sample_info.blank = zeros(8, 12);
            app.sample_info.selected_indices = [];
        end
        
        function subtract_background(app)
            fnames = fieldnames(app.data_struct);
            for field_num = 1:length(fnames)
                if length(fnames{field_num}) <= 5 || ~strcmp(fnames{field_num}(end - 4:end), 'bgsub')
                    data = double(app.data_struct.(fnames{field_num}));
                    median_bg = median(data(app.sample_info.blank > 0));
                    if size(data, 3) > 1
                        [i, j] = find(app.sample_info.blank);
                        median_bg = median(data(i, j, :));
                    end
                    bg_sub_data = double(0*data);
                    bg_sub_data(data > 0) = bsxfun(@minus, data(data > 0), median_bg);
                    app.data_struct.([fnames{field_num}, '_bgsub']) = bg_sub_data;
                end
            end
        end
        
        % Cell selection callback: UITable
        function data_table_CellSelection(app, event)
            indices = event.Indices;
            app.analysis.selected_indices = indices;
        end
        
        function on_key_press(app, event)
            if strcmp(event.Character, 'p')
                indices = app.analysis.selected_indices;
                data = app.analysis.uit.Data;
                buf_Venus = 150; buf_Scarlet = 15; buf_Luc = 35; buf_OD = 0.047;
                colors = jet(size(indices, 1));
                linestyles = {'-', '--', ':', '-.'};
                f0 = figure; f1 = figure;
                data_labels = {};
                for k = 1:size(indices, 1)
                    num_samples = data(indices(k, 1), indices(k, 2));
                    for n = 1:min(num_samples, 4)
                        data_labels{end + 1} = [app.analysis.labels{indices(k, 1) + 1, 1, 1}, 'x', app.analysis.labels{1, indices(k, 2) + 1, 1}, ':' ...
                            app.analysis.labels{indices(k, 1) + 1, indices(k, 2) + 1, n}];
                        scarlet = squeeze(app.analysis.scarlet(indices(k, 1), indices(k, 2), :, n));
                        luc = squeeze(app.analysis.luciferase(indices(k, 1), indices(k, 2), :, n));
                        venus = squeeze(app.analysis.venus(indices(k, 1), indices(k, 2), :, n));
                        od =squeeze(app.analysis.od(indices(k, 1), indices(k, 2), :, n));
                        
                        norm_scarlet = (scarlet - buf_Scarlet)./(od - buf_OD);
                        norm_luc = (luc - buf_Luc)./(od - buf_OD);
                        norm_venus = (venus - buf_Venus)./(od - buf_OD);
                        
                        figure(f0)
                        s_ax = subplot(2, 2, 1); hold on;
                        plot(scarlet, luc, ...
                            'LineStyle', linestyles{n}, 'Color', colors(k, :), 'Marker', 'o');
                        xlabel('Scarlet'); ylabel('Luciferase');
                        
                        subplot(2, 2, 3); hold on;
                        plot(norm_scarlet, norm_luc, ...
                            'LineStyle', linestyles{n}, 'Color', colors(k, :), 'Marker', 'o');
                        xlabel('Scarlet (OD normalized)'); ylabel('Luciferase (OD normalized)');
                        
                        subplot(2, 2, 2); hold on;
                        plot(venus, ...
                            'LineStyle', linestyles{n}, 'Color', colors(k, :), 'Marker', 'o');
                        ylabel('Venus')
                        
                        subplot(2, 2, 4); hold on;
                        plot(norm_venus, ...
                            'LineStyle', linestyles{n}, 'Color', colors(k, :), 'Marker', 'o');
                        ylabel('Venus (OD normalized)')
                        
                        figure(f1); hold on;
                        plot(od, ...
                            'LineStyle', linestyles{n}, 'Color', colors(k, :), 'Marker', 'o');
                        ylabel('OD')
                    end
                end
                legend(s_ax, data_labels)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportrawdataButton
        function ImportrawdataButtonPushed(app, event)
            default_dir = '/Users/ellarond/Dropbox/Lab/DNA/Dimerization measurements/Full dimer matrix/';
            [filename, pathname] = uigetfile(fullfile(default_dir, '*.txt'), 'Choose plate reader data file');
            if ~isempty(filename) %&& filename ~= 0
                parse_data(app, fullfile(pathname, filename));
                initialize_sample_info(app);
                app.sample_info.filename = filename;
                app.sample_info.folder = pathname;
                app.FilenameLabel.String = app.sample_info.filename;
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if ~isempty(app.sample_info.selected_indices)
                idx = sub2ind([8, 12], app.sample_info.selected_indices(:, 1), app.sample_info.selected_indices(:, 2));
                if strcmp(app.displaybox.Value, 'FY4')
                    fy4_text = inputdlg('FY4 strain name');
                    if ~isempty(fy4_text)
                        [app.sample_info.fy4{idx}] = deal(fy4_text{1});
                    end
                    app.UITable.Data = app.sample_info.fy4;
                elseif strcmp(app.displaybox.Value, 'FY5')
                    fy5_text = inputdlg('FY5 strain name');
                    if ~isempty(fy5_text)
                        [app.sample_info.fy5{idx}] = deal(fy5_text{1});
                    end
                    app.UITable.Data = app.sample_info.fy5;
                elseif strcmp(app.displaybox.Value, 'Dose')
                    dose_text = inputdlg('Dose (in ug/ml)');
                    if ~isempty(dose_text)
                        [app.sample_info.dose{idx}] = deal(dose_text{1});
                    end
                    app.UITable.Data = app.sample_info.dose;
                end
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.sample_info.selected_indices = indices;
        end

        % Value changed function: displaybox
        function displayboxValueChanged(app, event)
            if app.BackgroundsubtractedCheckBox.Value == 1 && isfield(app.data_struct, 'Venus_bgsub')
                suffix = '_bgsub';
                subtract_background(app)
            else
                suffix = '';
            end
            
            
            if app.ODnormalizedCheckBox.Value == 1
                scaling_factor = 1;
                buf_Venus = 150; buf_Scarlet = 15; buf_Luc = 35; buf_OD = 0.047;
                norm = scaling_factor*(app.data_struct.OD - buf_OD);
                norm(norm == 0) = scaling_factor;
            else
                buf_Venus = 0; buf_Scarlet = 0; buf_Luc = 0; buf_OD = 0;
                norm = ones(8, 12);
            end
            
            
            value = app.displaybox.Value{end};
            switch value
                case 'FY4'
                    app.UITable.Data = app.sample_info.fy4;
                case 'FY5'
                    app.UITable.Data = app.sample_info.fy5;
                case 'Dose'
                    app.UITable.Data = app.sample_info.dose;
                case 'Venus'
                    data = app.data_struct.(['Venus', suffix]);
                    norm_data = (data - buf_Venus)./norm;
                    norm_data(data == 0) = 0;
                    app.UITable.Data = norm_data;
                case 'Scarlet'
                    data = app.data_struct.(['Scarlet', suffix]);
                    norm_data = (data - buf_Scarlet)./norm;
                    norm_data(data == 0) = 0;
                    app.UITable.Data = norm_data;
                case 'OD'
                    data = app.data_struct.(['OD', suffix]);
                    app.UITable.Data = data;
                case 'Luciferase'
                    data = app.data_struct.(['Luc', suffix]);
                    data_auc = sum(data(:, :, 1:3), 3);
                    norm_data = (data_auc - buf_Luc)./norm;
                    norm_data(data_auc == 0) = 0;
                    app.UITable.Data = norm_data;
            end
            
            if iscell(app.UITable.Data)
                data_vals = str2double(app.UITable.Data);
            else
                data_vals = app.UITable.Data;
            end
            
            if length(app.displaybox.Value) == 1
                app.plot_data.XLabel = app.displaybox.Value;
                app.plot_data.Xdata = data_vals;
            elseif length(app.displaybox.Value) == 2
                app.plot_data.YLabel = app.displaybox.Value{2};
                app.plot_data.Ydata = data_vals;
            end
        end

        % Button pushed function: SetblankButton
        function SetblankButtonPushed(app, event)
            app.sample_info.blank = 0*app.sample_info.blank;
            if ~isempty(app.sample_info.selected_indices)
                idx = sub2ind([8, 12], app.sample_info.selected_indices(:, 1), app.sample_info.selected_indices(:, 2));
                [app.sample_info.fy4{idx}] = deal('blank');
                [app.sample_info.fy5{idx}] = deal('blank');
                app.sample_info.blank(idx) = 1;
                subtract_background(app)
                app.displaybox.Value = 'FY4';
                displayboxValueChanged(app, []);
            end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            [outfilename, outpathname] = uiputfile(fullfile(app.sample_info.folder, [app.sample_info.filename(1:end - 4), '_analyzed.mat']));
            plate_info = app.sample_info;
            plate_data = app.data_struct;
            save(fullfile(outpathname, outfilename), 'plate_info', 'plate_data');
        end

        % Button pushed function: LoadplatedataButton
        function LoadplatedataButtonPushed(app, event)
            default_dir = '/Users/ellarond/Dropbox/Lab/DNA/Dimerization measurements/Full dimer matrix/';
            [infilename, inpathname] = uigetfile(fullfile(default_dir, '*.mat'));
            if ~isempty(infilename)
                answer = questdlg('Input plate template only?');
                var = load(fullfile(inpathname, infilename));
                switch answer
                    case 'Yes'
                        old_filename = app.sample_info.filename;
                        old_folder = app.sample_info.folder;
                        app.sample_info = var.plate_info;
                        app.sample_info.filename = old_filename;
                        app.sample_info.folder = old_folder;
                        %parse_data(app, fullfile(inpathname, infilename));
                    case 'No'
                        app.sample_info = var.plate_info;
                        app.data_struct = var.plate_data;
                end
                app.displaybox.Enable = 'on';
                displayboxValueChanged(app, []);
                app.FilenameLabel.Text = infilename;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            if length(app.displaybox.Value) == 2
                indices = app.sample_info.selected_indices;
                if ~isempty(indices)
                    answer = questdlg('Dimension to group data?', '', 'Row-wise', 'Column-wise', 'Column-wise');
                    f = figure; ax = axes(f);
                    switch answer
                        case 'Row-wise'
                            group_index = 1;
                        case 'Column-wise'
                            group_index = 2;
                    end
                    
                    groups = unique(indices(:, group_index));
                    
                    colors = colorcube(length(groups) + 5);
                    legend_labels = {};
                    axes(ax); hold on;
                    for g = 1:length(groups)
                        plot_indices = find(indices(:, group_index) == groups(g));
                        matrix_indices = sub2ind([8, 12], indices(plot_indices, 1), indices(plot_indices, 2));
                        legend_labels{end + 1} = [app.sample_info.fy4{matrix_indices(1)}, 'x', app.sample_info.fy5{matrix_indices(1)}];
                        Xvals = app.plot_data.Xdata(matrix_indices);
                        Yvals = app.plot_data.Ydata(matrix_indices);
                        plot(Xvals, Yvals, 'LineStyle', "-", ...
                            "Marker","o", "Color", colors(g, :));
                    end
                    ax.XLabel.String = app.plot_data.XLabel;
                    ax.YLabel.String = app.plot_data.YLabel;
                    ax.Title.String = app.sample_info.filename(1:end - 4);
                    legend(ax, legend_labels);
                end
            end
        end

        % Value changed function: ODnormalizedCheckBox
        function ODnormalizedCheckBoxValueChanged(app, event)
            value = app.ODnormalizedCheckBox.Value;
            displayboxValueChanged(app, []);
        end

        % Value changed function: BackgroundsubtractedCheckBox
        function BackgroundsubtractedCheckBoxValueChanged(app, event)
            value = app.BackgroundsubtractedCheckBox.Value;
            displayboxValueChanged(app, []);
        end

        % Button pushed function: ViewAnalysisButton
        function ViewAnalysisButtonPushed(app, event)
            if length(fieldnames(app.analysis)) > 0
                if ishandle(app.analysis.data_table_figure)
                    %app.analysis.data_table_figure = figure('Name', 'Data annotations');
                    figure(app.analysis.data_table_figure);
                else
                    app.analysis.data_table_figure = uifigure('Name', 'Data annotations');
                    app.analysis.data_table_figure.KeyPressFcn = createCallbackFcn(app, @on_key_press, true);
                end
                
                num_samples = 0*app.analysis.luciferase(:, :, 1, 1);
                for rn = 1:size(app.analysis.luciferase, 1)
                    for cn = 1:size(app.analysis.luciferase, 2)
                        num_samples(rn, cn) = length(find(app.analysis.luciferase(rn, cn, 1, :)));
                    end
                end
                app.analysis.uit = uitable(app.analysis.data_table_figure);
                app.analysis.uit.Data = num_samples;
                app.analysis.uit.CellSelectionCallback = createCallbackFcn(app, @data_table_CellSelection, true);
                app.analysis.uit.RowName = app.analysis.labels(2:end, 1, 1);
                app.analysis.uit.ColumnName = app.analysis.labels(1, 2:end, 1);
                %cellplot(app.analysis.labels);
            end
        end

        % Button pushed function: AddSelectedButton
        function AddSelectedButtonPushed(app, event)
            if length(fieldnames(app.analysis)) > 0
                indices = sub2ind([8, 12], app.sample_info.selected_indices(:, 1), app.sample_info.selected_indices(:, 2));
                suffix = '';
                data = app.data_struct.(['Luc', suffix]);
                data_auc = sum(data(:, :, 1:3), 3);
%                 figure; hold on;
%                 for i = 1:length(indices)
%                     plot(squeeze(data(app.sample_info.selected_indices(i, 1), app.sample_info.selected_indices(i, 2), :)), 'Marker', 'o')
%                 end
                for i = 1:length(indices)
                    
                    rn = find(strcmp(app.analysis.genes, app.sample_info.fy4{indices(i)}));
                    cn = find(strcmp(app.analysis.genes, app.sample_info.fy5{indices(i)}));
                    parts = split(app.FilenameLabel.Text, '_');
                    
                    
                    if ~isempty(rn) && ~isempty(cn)
                        
                        k = 0;
                        % find index to add values to
                        for rep = 1:size(app.analysis.labels, 3)
                            if strcmp(app.analysis.labels(rn + 1, cn + 1, rep), parts{1}) == 1
                                k = rep - 1;
                                break;
                            end
                            
                            if ~isempty(app.analysis.labels{rn + 1, cn + 1, rep})
                                k = rep;
                            end
                        end
                        app.analysis.labels{rn + 1, cn + 1, k + 1} = parts{1};
                    end
                    
                    % expand matrices if necessary
                    if k + 1 > size(app.analysis.luciferase, 4)
                        app.analysis.luciferase = cat(4, app.analysis.luciferase, 0*app.analysis.luciferase);
                        app.analysis.scarlet = cat(4, app.analysis.scarlet, 0*app.analysis.scarlet);
                        app.analysis.venus = cat(4, app.analysis.venus, 0*app.analysis.venus);
                        app.analysis.od = cat(4, app.analysis.od, 0*app.analysis.od);
                        
                        app.analysis.blank.luciferase = cat(4, app.analysis.blank.luciferase, 0*app.analysis.blank.luciferase);
                        app.analysis.blank.scarlet = cat(4, app.analysis.blank.scarlet, 0*app.analysis.blank.scarlet);
                        app.analysis.blank.venus = cat(4, app.analysis.blank.venus, 0*app.analysis.blank.venus);
                        app.analysis.blank.od = cat(4, app.analysis.blank.od, 0*app.analysis.blank.od);
                        
                    end
                    dn = find(strcmp(app.analysis.doses, app.sample_info.dose{indices(i)}));
                    app.analysis.luciferase(rn, cn, dn, k + 1) = data_auc(indices(i));
                    app.analysis.blank.luciferase(rn, cn, dn, k + 1) = median(data(app.sample_info.blank > 0));
                    
                    data = app.data_struct.(['Scarlet', suffix]);
                    app.analysis.scarlet(rn, cn, dn, k + 1) = data(indices(i));
                    app.analysis.blank.scarlet(rn, cn, dn, k + 1) = median(data(app.sample_info.blank > 0));
                    
                    data = app.data_struct.(['Venus', suffix]);
                    app.analysis.venus(rn, cn, dn, k + 1) = data(indices(i));
                    app.analysis.blank.venus(rn, cn, dn, k + 1) = median(data(app.sample_info.blank > 0));
                    
                    data = app.data_struct.(['OD', suffix]);
                    app.analysis.od(rn, cn, dn, k + 1) = data(indices(i));
                    app.analysis.blank.od(rn, cn, dn, k + 1) = median(data(app.sample_info.blank > 0));
                    
                end
                
                
            end
        end

        % Button pushed function: AddAllButton
        function AddAllButtonPushed(app, event)
            
        end

        % Button pushed function: LoadAnalysisButton
        function LoadAnalysisButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.mat', ['Pick an analysis ' ...
                'file to load']);
            if ~isempty(filename)
                if length(fieldnames(app.analysis)) > 0
                    response = questdlg('Save analysis?');
                    if strcmp(response, 'Yes')
                        SaveAnalysisButtonPushed(app, []);
                    end
                end
                if isfile(fullfile(pathname, filename))
                    temp = load(fullfile(pathname, filename));
                    app.analysis = temp.analysis;
                    app.analysis.path = fullfile(pathname, filename);
                else
                    app.analysis.path = fullfile(pathname, filename);
                    % initialize analysis structure
                    app.analysis.labels = cell(15, 16);
                    colnames = {'Ascl1', 'Atoh8', 'Hes1', 'Hes5', 'Hey1' ...
                        'Olig1', 'Olig2', 'Id1', 'Id2', 'Id3', 'Id4' ...
                        'E47', 'Tcf4', 'Tcf12', 'neg'};
                    app.analysis.genes = colnames;
                    app.analysis.labels(1, 2:end) = colnames;
                    app.analysis.labels(2:end, 1) = colnames(1:end - 1);
                    
                    app.analysis.doses = {'0', '5', '10', '25', '50'};
                    app.analysis.luciferase = zeros(length(colnames) - 1, ...
                        length(colnames), length(app.analysis.doses));
                    app.analysis.venus = 0*app.analysis.luciferase;
                    app.analysis.scarlet = 0*app.analysis.luciferase;
                    app.analysis.od = 0*app.analysis.luciferase;
                    
                    % initialize a matrix of 'blank' values
                    app.analysis.blank.luciferase = 0*app.analysis.luciferase;
                    app.analysis.blank.venus = 0*app.analysis.luciferase;
                    app.analysis.blank.scarlet = 0*app.analysis.luciferase;
                    app.analysis.blank.od = 0*app.analysis.luciferase;
                    
                end
                app.AnalysisFilenameLabel.Text = filename;
            end
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.mat', ['Pick an analysis ' ...
                'file to load']);
            app.analysis.path = fullfile(pathname, filename);
            
            if length(fieldnames(app.analysis)) > 0
                analysis = app.analysis;
                save(app.analysis.path, 'analysis')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {391, 391};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {170, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImportrawdataButton = uibutton(app.LeftPanel, 'push');
            app.ImportrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportrawdataButtonPushed, true);
            app.ImportrawdataButton.Text = 'Import raw data';
            app.displaybox = uilistbox(app.LeftPanel);
            app.displaybox.Items = {'FY4', 'FY5', 'Dose', 'Venus', 'Scarlet', 'OD', 'Luciferase'};
            app.displaybox.Multiselect = 'on';
            app.displaybox.ValueChangedFcn = createCallbackFcn(app, @displayboxValueChanged, true);
            app.displaybox.Enable = 'off';
            app.displaybox.Value = {'FY4'};
            app.EditButton = uibutton(app.LeftPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.SavedataButton = uibutton(app.LeftPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LoadplatedataButton = uibutton(app.LeftPanel, 'push');
            app.LoadplatedataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadplatedataButtonPushed, true);
            app.LoadplatedataButton.Text = 'Load plate/data';
            app.AddSelectedButton = uibutton(app.LeftPanel, 'push');
            app.AddSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectedButtonPushed, true);
            app.AddSelectedButton.Text = 'Add';
            app.AddAllButton = uibutton(app.LeftPanel, 'push');
            app.AddAllButton.ButtonPushedFcn = createCallbackFcn(app, @AddAllButtonPushed, true);
            app.AddAllButton.Text = 'Add all';
            app.LoadAnalysisButton = uibutton(app.LeftPanel, 'push');
            app.LoadAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalysisButtonPushed, true);
            app.LoadAnalysisButton.Text = 'Load';
            app.SaveAnalysisButton = uibutton(app.LeftPanel, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Text = 'Save';
            app.AnalyzeLabel = uilabel(app.LeftPanel);
            app.AnalyzeLabel.Text = 'Analyze';
            app.AnnotateLabel = uilabel(app.LeftPanel);
            app.AnnotateLabel.Text = 'Annotate';
            app.ViewAnalysisButton = uibutton(app.LeftPanel, 'push');
            app.ViewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAnalysisButtonPushed, true);
            app.ViewAnalysisButton.Text = 'View';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.BackgroundsubtractedCheckBox = uicheckbox(app.RightPanel);
            app.BackgroundsubtractedCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundsubtractedCheckBoxValueChanged, true);
            app.BackgroundsubtractedCheckBox.Text = 'Background subtracted';
            app.ODnormalizedCheckBox = uicheckbox(app.RightPanel);
            app.ODnormalizedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ODnormalizedCheckBoxValueChanged, true);
            app.ODnormalizedCheckBox.Text = 'OD normalized';
            app.SetblankButton = uibutton(app.RightPanel, 'push');
            app.SetblankButton.ButtonPushedFcn = createCallbackFcn(app, @SetblankButtonPushed, true);
            app.SetblankButton.Text = 'Set blank';
            app.FileLabel = uilabel(app.RightPanel);
            app.FileLabel.Text = 'File:';
            app.FilenameLabel = uilabel(app.RightPanel);
            app.FilenameLabel.Text = '';
            app.AnalysisLabel = uilabel(app.RightPanel);
            app.AnalysisLabel.Text = 'Analysis:';
            app.AnalysisFilenameLabel = uilabel(app.RightPanel);
            app.AnalysisFilenameLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DimerOrganizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end